classdef ContentScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Toolbar         matlab.ui.container.Toolbar
        BackButton      matlab.ui.container.toolbar.PushTool
        Panel           matlab.ui.container.Panel
        LabelFattori    matlab.ui.control.Label
        TabGroup        matlab.ui.container.TabGroup
        CH4Tab          matlab.ui.container.Tab
        Plot1           matlab.ui.control.UIAxes
        CH5Tab          matlab.ui.container.Tab
        Plot2           matlab.ui.control.UIAxes
        CH45Tab         matlab.ui.container.Tab
        Plot3           matlab.ui.control.UIAxes
        LabelRiepilogo  matlab.ui.control.Label
    end

    
    properties (Access = private)
        pathToMLAPP = fileparts(mfilename('fullpath'));
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Posizino la finestra correttamente al centro dello schermo in
            % maniera indipendente dalla risoluzione di quest'ultimo
            % Ottengo la larghezza e l'altezza dello schermo
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
        
            % Ottengo la larghezza e l'altezza della finestra dell'app
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
        
            % Calcolo le coordinate x e y per posizionare la finestra al centro
            x = (screenWidth - appWidth) / 2;
            y = (screenHeight - appHeight) / 2;
        
            % Imposto la posizione della finestra
            app.UIFigure.Position(1) = x;
            app.UIFigure.Position(2) = y;
            
            app.UIFigure.Name = 'Sea ​​Surface Temperature Calculator';

            whichSatellite = evalin('base', 'whichSatellite');
            whichTecnique = evalin('base', 'whichTecnique');
            whichVariable = evalin('base', 'whichVariable');

            stringaRiepilogo = sprintf('Satellite: %s\nRegression calculation technique: %s\nRegression variable: %s',whichSatellite, whichTecnique, whichVariable);
            app.LabelRiepilogo.Text = stringaRiepilogo;
            
            % Aggiungo il path del Backend ai path di Matlab per poter
            % chiamare gli script contenuti in esso (es: loadChannels_NOA)
            path0 = fullfile(app.pathToMLAPP, '..', 'backend', 'src');
            addpath(path0)

            % Creo i path per le risorse del Backend in modo che siano
            % reperibili a partire dall'attuale path del Frontend
            path1 = fullfile(path0, '..', 'res', 'SEA_SURFACE_TRO_IASI_IFOV0.mat');
            path2 = fullfile(path0, '..', 'res', 'rtcoef_metop_1_avhrr_srf_ch05.txt');
            path3 = fullfile(path0, '..', 'res', 'rtcoef_metop_1_avhrr_srf_ch06.txt');
            path4 = fullfile(path0, '..', 'res', 'rtcoef_noaa_19_avhrr_srf_ch05.txt');
            path5 = fullfile(path0, '..', 'res', 'rtcoef_noaa_19_avhrr_srf_ch06.txt');

            % Verifico quali valori ha inserito l'utente e in base a
            % quest'ultimi lancio lo script corretto
            if (strcmp(whichSatellite,'MetOp-1'))
                [ch4,ch5,radiance_ch4, brightTemp_ch4, radiance_ch5, brightTemp_ch5] = loadChannels_METOP(true, app, path1, path2, path3);
                if(strcmp(whichTecnique,'Split Window'))
                    if(strcmp(whichVariable,'Brightness Temperature (T)'))
                        [delta,bias,rmse,dum,R2] = splitWindow_T(ch4,ch5,brightTemp_ch4,brightTemp_ch5, true, app, path1);
                    else
                        [delta,bias,rmse,dum,R2] = splitWindow_R(ch4,ch5,radiance_ch4,radiance_ch5, true, app, path1);
                    end
                else
                    if(strcmp(whichVariable,'Brightness Temperature (T)'))
                        [delta,bias,rmse,dum,R2] = corLin_T(ch4,ch5,brightTemp_ch4,brightTemp_ch5, true, app, path1);
                    else
                        [delta,bias,rmse,dum,R2] = corLin_R(ch4,ch5,radiance_ch4,radiance_ch5, true, app, path1);
                    end
                end
            else
                [ch4,ch5,radiance_ch4, brightTemp_ch4, radiance_ch5, brightTemp_ch5] = loadChannels_NOAA(true, app, path1, path4, path5);
                if(strcmp(whichTecnique,'Split Window'))
                    if(strcmp(whichVariable,'Brightness Temperature (T)'))
                        [delta,bias,rmse,dum,R2] = splitWindow_T(ch4,ch5,brightTemp_ch4,brightTemp_ch5, true, app, path1);
                    else
                        [delta,bias,rmse,dum,R2] = splitWindow_R(ch4,ch5,radiance_ch4,radiance_ch5, true, app, path1);
                    end
                else
                    if(strcmp(whichVariable,'Brightness Temperature (T)'))
                        [delta,bias,rmse,dum,R2] = corLin_T(ch4,ch5,brightTemp_ch4,brightTemp_ch5, true, app, path1);
                    else
                        [delta,bias,rmse,dum,R2] = corLin_R(ch4,ch5,radiance_ch4,radiance_ch5, true, app, path1);
                    end
                end
            end

            stringaFattori = sprintf('Bias= %s\nRMSE= %s\nR^2= %s',bias, rmse, R2);
            app.LabelFattori.Text = stringaFattori;
        end

        % Callback function: BackButton
        function BackButtonClicked(app, event)
            assignin('base', 'isStartedFromContentsScreen', true);
            % Chiudo lo schermo dei contenuti
            delete(app);
            % Apro lo schermo di benvenuto
            WelcomeScreen
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Pulisco il workspace
            evalin('base', 'clear isStartedFromContentsScreen');
            evalin('base', 'clear whichSatellite');
            evalin('base', 'clear whichTecnique');
            evalin('base', 'clear whichVariable');
            clc;

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.BackButton = uipushtool(app.Toolbar);
            app.BackButton.Tooltip = {'Return to the previous screen'};
            app.BackButton.ClickedCallback = createCallbackFcn(app, @BackButtonClicked, true);
            app.BackButton.Icon = fullfile(pathToMLAPP, 'res', 'back_button.png');
            app.Panel = uipanel(app.UIFigure);
            app.LabelRiepilogo = uilabel(app.Panel);
            app.LabelRiepilogo.Text = {'xxx'; 'xxx'; 'xxx'};
            app.TabGroup = uitabgroup(app.Panel);
            app.CH4Tab = uitab(app.TabGroup);
            app.CH4Tab.Tooltip = {'Channel 4'};
            app.CH4Tab.Title = 'CH 4';
            app.Plot1 = uiaxes(app.CH4Tab);
            title(app.Plot1, 'Title')
            xlabel(app.Plot1, 'x')
            ylabel(app.Plot1, 'y')
            app.CH5Tab = uitab(app.TabGroup);
            app.CH5Tab.Tooltip = {'Channel 5'};
            app.CH5Tab.Title = 'CH 5';
            app.Plot2 = uiaxes(app.CH5Tab);
            title(app.Plot2, 'Title')
            xlabel(app.Plot2, 'x')
            ylabel(app.Plot2, 'y')
            app.CH45Tab = uitab(app.TabGroup);
            app.CH45Tab.Tooltip = {'Combination of channel 4 and 5'};
            app.CH45Tab.Title = 'CH 4&5';
            app.Plot3 = uiaxes(app.CH45Tab);
            title(app.Plot3, 'Title')
            xlabel(app.Plot3, 'x')
            ylabel(app.Plot3, 'y')
            app.LabelFattori = uilabel(app.Panel);
            app.LabelFattori.Text = {'xxx'; 'xxx'; 'xxx'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContentScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end