classdef WelcomeScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        InfoPanel                    matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        GeographicalAreaButtonGroup  matlab.ui.container.ButtonGroup
        SubregionextractionButton    matlab.ui.control.RadioButton
        WholeavailableregionButton   matlab.ui.control.RadioButton
        DownloadProcessandViewDataButton  matlab.ui.control.Button
        ModelButtonGroup             matlab.ui.container.ButtonGroup
        ECEarth3CCEuropeButton       matlab.ui.control.RadioButton
        HadGEM3GC31MMUKButton        matlab.ui.control.RadioButton
        NorESM2MMNorwayButton        matlab.ui.control.RadioButton
        AWICM11MRGermanyButton       matlab.ui.control.RadioButton
        ECEarth3VegLREuropeButton    matlab.ui.control.RadioButton
        HadGEM3GC31LLUKButton        matlab.ui.control.RadioButton
        CMCCCM2SR5ItalyButton        matlab.ui.control.RadioButton
        CNRMCM61HRFranceButton       matlab.ui.control.RadioButton
        ECEarth3EuropeButton         matlab.ui.control.RadioButton
        ECEarth3VegEuropeButton      matlab.ui.control.RadioButton
        ECEarth3AerChemEuropeButton  matlab.ui.control.RadioButton
        CNRMESM21FranceButton        matlab.ui.control.RadioButton
        CNRMCM61FranceButton         matlab.ui.control.RadioButton
        VariableButtonGroup          matlab.ui.container.ButtonGroup
        SnowdepthButton              matlab.ui.control.RadioButton
        SnowfallfluxButton           matlab.ui.control.RadioButton
        SurfacesnowamountButton      matlab.ui.control.RadioButton
        Panel_3                      matlab.ui.container.Panel
        Image2                       matlab.ui.control.Image
        Panel_2                      matlab.ui.container.Panel
        Label0                       matlab.ui.control.Label
        YearPanel                    matlab.ui.container.Panel
        StartingYearLabel            matlab.ui.control.Label
        FinalYearLabel               matlab.ui.control.Label
        FinalYearSpinner             matlab.ui.control.Spinner
        StartingYearSpinner          matlab.ui.control.Spinner
        ExperimentButtonGroup        matlab.ui.container.ButtonGroup
        SSP585Button                 matlab.ui.control.RadioButton
        SSP460Button                 matlab.ui.control.RadioButton
        SSP534OSButton               matlab.ui.control.RadioButton
        SSP126Button                 matlab.ui.control.RadioButton
        SSP119Button                 matlab.ui.control.RadioButton
        SSP434Button                 matlab.ui.control.RadioButton
        SSP245Button                 matlab.ui.control.RadioButton
        SSP370Button                 matlab.ui.control.RadioButton
    end


    properties (Access = private)

        pathToMLAPP = fileparts(mfilename('fullpath'));
        pathToBackend = fullfile(fileparts(mfilename('fullpath')), '..', 'backend');

        % Creazione del dizionario degli esperimenti
        experimentDictionary = containers.Map;

        % Creazione del dizionario delle variabili
        variableDictionary = containers.Map;

    end


    methods (Access = private)

        function enableAvailableModel(app,choiceExperiment,choiceVariable,startYear,endYear)
            [~, ~, ~, models] = selezionaParametri(choiceExperiment, choiceVariable, startYear, endYear);

            if any(ismember(app.ECEarth3CCEuropeButton.Text, models))
                app.ECEarth3CCEuropeButton.Enable = 'on';
                app.ECEarth3CCEuropeButton.Value = true;
                assignin('base', 'whichModel', app.ECEarth3CCEuropeButton.Text);
            else
                app.ECEarth3CCEuropeButton.Enable = 'off';
                if app.ECEarth3CCEuropeButton.Value == true
                    app.ECEarth3CCEuropeButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.HadGEM3GC31MMUKButton.Text, models))
                app.HadGEM3GC31MMUKButton.Enable = 'on';
                app.HadGEM3GC31MMUKButton.Value = true;
                assignin('base', 'whichModel', app.HadGEM3GC31MMUKButton.Text);
            else
                app.HadGEM3GC31MMUKButton.Enable = 'off';
                if app.HadGEM3GC31MMUKButton.Value == true
                    app.HadGEM3GC31MMUKButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.NorESM2MMNorwayButton.Text, models))
                app.NorESM2MMNorwayButton.Enable = 'on';
                app.NorESM2MMNorwayButton.Value = true;
                assignin('base', 'whichModel', app.NorESM2MMNorwayButton.Text);
            else
                app.NorESM2MMNorwayButton.Enable = 'off';
                if app.NorESM2MMNorwayButton.Value == true
                    app.NorESM2MMNorwayButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.AWICM11MRGermanyButton.Text, models))
                app.AWICM11MRGermanyButton.Enable = 'on';
                app.AWICM11MRGermanyButton.Value = true;
                assignin('base', 'whichModel', app.AWICM11MRGermanyButton.Text);
            else
                app.AWICM11MRGermanyButton.Enable = 'off';
                if app.AWICM11MRGermanyButton.Value == true
                    app.AWICM11MRGermanyButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.ECEarth3VegLREuropeButton.Text, models))
                app.ECEarth3VegLREuropeButton.Enable = 'on';
                app.ECEarth3VegLREuropeButton.Value = true;
                assignin('base', 'whichModel', app.ECEarth3VegLREuropeButton.Text);
            else
                app.ECEarth3VegLREuropeButton.Enable = 'off';
                if app.ECEarth3VegLREuropeButton.Value == true
                    app.ECEarth3VegLREuropeButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.HadGEM3GC31LLUKButton.Text, models))
                app.HadGEM3GC31LLUKButton.Enable = 'on';
                app.HadGEM3GC31LLUKButton.Value = true;
                assignin('base', 'whichModel', app.HadGEM3GC31LLUKButton.Text);
            else
                app.HadGEM3GC31LLUKButton.Enable = 'off';
                if app.HadGEM3GC31LLUKButton.Value == true
                    app.HadGEM3GC31LLUKButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.CMCCCM2SR5ItalyButton.Text, models))
                app.CMCCCM2SR5ItalyButton.Enable = 'on';
                app.CMCCCM2SR5ItalyButton.Value = true;
                assignin('base', 'whichModel', app.CMCCCM2SR5ItalyButton.Text);
            else
                app.CMCCCM2SR5ItalyButton.Enable = 'off';
                if app.CMCCCM2SR5ItalyButton.Value == true
                    app.CMCCCM2SR5ItalyButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.CNRMCM61HRFranceButton.Text, models))
                app.CNRMCM61HRFranceButton.Enable = 'on';
                app.CNRMCM61HRFranceButton.Value = true;
                assignin('base', 'whichModel', app.CNRMCM61HRFranceButton.Text);
            else
                app.CNRMCM61HRFranceButton.Enable = 'off';
                if app.CNRMCM61HRFranceButton.Value == true
                    app.CNRMCM61HRFranceButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.ECEarth3EuropeButton.Text, models))
                app.ECEarth3EuropeButton.Enable = 'on';
                app.ECEarth3EuropeButton.Value = true;
                assignin('base', 'whichModel', app.ECEarth3EuropeButton.Text);
            else
                app.ECEarth3EuropeButton.Enable = 'off';
                if app.ECEarth3EuropeButton.Value == true
                    app.ECEarth3EuropeButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.ECEarth3VegEuropeButton.Text, models))
                app.ECEarth3VegEuropeButton.Enable = 'on';
                app.ECEarth3VegEuropeButton.Value = true;
                assignin('base', 'whichModel', app.ECEarth3VegEuropeButton.Text);
            else
                app.ECEarth3VegEuropeButton.Enable = 'off';
                if app.ECEarth3VegEuropeButton.Value == true
                    app.ECEarth3VegEuropeButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.ECEarth3AerChemEuropeButton.Text, models))
                app.ECEarth3AerChemEuropeButton.Enable = 'on';
                app.ECEarth3AerChemEuropeButton.Value = true;
                assignin('base', 'whichModel', app.ECEarth3AerChemEuropeButton.Text);
            else
                app.ECEarth3AerChemEuropeButton.Enable = 'off';
                if app.ECEarth3AerChemEuropeButton.Value == true
                    app.ECEarth3AerChemEuropeButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.CNRMESM21FranceButton.Text, models))
                app.CNRMESM21FranceButton.Enable = 'on';
                app.CNRMESM21FranceButton.Value = true;
                assignin('base', 'whichModel', app.CNRMESM21FranceButton.Text);
            else
                app.CNRMESM21FranceButton.Enable = 'off';
                if app.CNRMESM21FranceButton.Value == true
                    app.CNRMESM21FranceButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

            if any(ismember(app.CNRMCM61FranceButton.Text, models))
                app.CNRMCM61FranceButton.Enable = 'on';
                app.CNRMCM61FranceButton.Value = true;
                assignin('base', 'whichModel', app.CNRMCM61FranceButton.Text);
            else
                app.CNRMCM61FranceButton.Enable = 'off';
                if app.CNRMCM61FranceButton.Value == true
                    app.CNRMCM61FranceButton.Value = false;
                    evalin('base', 'clear whichModel');
                end
            end

        end

        function activeSpecificExperiment(app,specificExperiment)

            if (strcmp(app.SSP585Button.Text, specificExperiment))
                app.SSP585Button.Value = true;
                return;
            end

            if (strcmp(app.SSP460Button.Text, specificExperiment))
                app.SSP460Button.Value = true;
                return;
            end

            if (strcmp(app.SSP534OSButton.Text, specificExperiment))
                app.SSP534OSButton.Value = true;
                return;
            end

            if (strcmp(app.SSP126Button.Text, specificExperiment))
                app.SSP126Button.Value = true;
                return;
            end

            if (strcmp(app.SSP119Button.Text, specificExperiment))
                app.SSP119Button.Value = true;
                return;
            end

            if (strcmp(app.SSP434Button.Text, specificExperiment))
                app.SSP434Button.Value = true;
                return;
            end

            if (strcmp(app.SSP245Button.Text, specificExperiment))
                app.SSP245Button.Value = true;
                return;
            end

            if (strcmp(app.SSP370Button.Text, specificExperiment))
                app.SSP370Button.Value = true;
                return;
            end

        end

        function activeSpecificVariable(app,specificVariable)

            if (strcmp(app.SnowdepthButton.Text, specificVariable))
                app.SnowdepthButton.Value = true;
                return;
            end

            if (strcmp(app.SnowfallfluxButton.Text, specificVariable))
                app.SnowfallfluxButton.Value = true;
                return;
            end

            if (strcmp(app.SurfacesnowamountButton.Text, specificVariable))
                app.SurfacesnowamountButton.Value = true;
                return;
            end

        end

        function activeSpecificModel(app,specificModel)

            if (strcmp(app.ECEarth3CCEuropeButton.Text, specificModel))
                app.ECEarth3CCEuropeButton.Value = true;
                assignin('base', 'whichModel', app.ECEarth3CCEuropeButton.Text);
                return;
            end

            if (strcmp(app.HadGEM3GC31MMUKButton.Text, specificModel))
                app.HadGEM3GC31MMUKButton.Value = true;
                assignin('base', 'whichModel', app.HadGEM3GC31MMUKButton.Text);
                return;
            end

            if (strcmp(app.NorESM2MMNorwayButton.Text, specificModel))
                app.NorESM2MMNorwayButton.Value = true;
                assignin('base', 'whichModel', app.NorESM2MMNorwayButton.Text);
                return;
            end

            if (strcmp(app.AWICM11MRGermanyButton.Text, specificModel))
                app.AWICM11MRGermanyButton.Value = true;
                assignin('base', 'whichModel', app.AWICM11MRGermanyButton.Text);
                return;
            end

            if (strcmp(app.ECEarth3VegLREuropeButton.Text, specificModel))
                app.ECEarth3VegLREuropeButton.Value = true;
                assignin('base', 'whichModel', app.ECEarth3VegLREuropeButton.Text);
                return;
            end

            if (strcmp(app.HadGEM3GC31LLUKButton.Text, specificModel))
                app.HadGEM3GC31LLUKButton.Value = true;
                assignin('base', 'whichModel', app.HadGEM3GC31LLUKButton.Text);
                return;
            end

            if (strcmp(app.CMCCCM2SR5ItalyButton.Text, specificModel))
                app.CMCCCM2SR5ItalyButton.Value = true;
                assignin('base', 'whichModel', app.CMCCCM2SR5ItalyButton.Text);
                return;
            end

            if (strcmp(app.CNRMCM61HRFranceButton.Text, specificModel))
                app.CNRMCM61HRFranceButton.Value = true;
                assignin('base', 'whichModel', app.CNRMCM61HRFranceButton.Text);
            end

            if (strcmp(app.ECEarth3EuropeButton.Text, specificModel))
                app.ECEarth3EuropeButton.Value = true;
                assignin('base', 'whichModel', app.ECEarth3EuropeButton.Text);
                return;
            end

            if (strcmp(app.ECEarth3VegEuropeButton.Text, specificModel))
                app.ECEarth3VegEuropeButton.Value = true;
                assignin('base', 'whichModel', app.ECEarth3VegEuropeButton.Text);
                return;
            end

            if (strcmp(app.ECEarth3AerChemEuropeButton.Text, specificModel))
                app.ECEarth3AerChemEuropeButton.Value = true;
                assignin('base', 'whichModel', app.ECEarth3AerChemEuropeButton.Text);
                return;
            end

            if (strcmp(app.CNRMESM21FranceButton.Text, specificModel))
                app.CNRMESM21FranceButton.Value = true;
                assignin('base', 'whichModel', app.CNRMESM21FranceButton.Text);
                return;
            end

            if (strcmp(app.CNRMCM61FranceButton.Text, specificModel))
                app.CNRMCM61FranceButton.Value = true;
                assignin('base', 'whichModel', app.CNRMCM61FranceButton.Text);
                return;
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % TODO: C'è un errore che pare non detrministico, che si presenta a volte quando viene lanciato il secondo schermo. Esso pare essere un errore della grafica di Matlab, investigare e valutare se è risolvibile e/o aggirabile.
            
            % Posizino la finestra correttamente al centro dello schermo in
            % maniera indipendente dalla risoluzione di quest'ultimo
            % Ottengo la larghezza e l'altezza dello schermo
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);

            % Ottengo la larghezza e l'altezza della finestra dell'app
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);

            % Calcolo le coordinate x e y per posizionare la finestra al centro
            x = (screenWidth - appWidth) / 2;
            y = (screenHeight - appHeight) / 2;

            % Imposto la posizione della finestra
            app.UIFigure.Position(1) = x;
            app.UIFigure.Position(2) = y;

            app.UIFigure.Name = 'CMIP6 Climate Projections Visualizer';
            borderColor = [0.64, 0.08, 0.18];
            app.Panel_2.BorderColor = borderColor;
            app.Panel_3.BorderColor = borderColor;
            app.ExperimentButtonGroup.BorderColor = borderColor;
            app.YearPanel.BorderColor = borderColor;
            app.YearPanel.BorderColor = borderColor;
            app.VariableButtonGroup.BorderColor = borderColor;
            app.ModelButtonGroup.BorderColor = borderColor;
            app.GeographicalAreaButtonGroup.BorderColor = borderColor;
            app.InfoPanel.BorderColor = borderColor;

            app.experimentDictionary('SSP1-1.9') = 1;
            app.experimentDictionary('SSP1-2.6') = 2;
            app.experimentDictionary('SSP2-4.5') = 3;
            app.experimentDictionary('SSP3-7.0') = 4;
            app.experimentDictionary('SSP4-3.4') = 5;
            app.experimentDictionary('SSP4-6.0') = 6;
            app.experimentDictionary('SSP5-3.4OS') = 7;
            app.experimentDictionary('SSP5-8.5') = 8;
            app.variableDictionary('Snow depth') = 1;
            app.variableDictionary('Snowfall flux') = 2;
            app.variableDictionary('Surface snow amount') = 3;

            try
                isStartedFromContentsScreen = evalin('base', 'isStartedFromContentsScreen');
                if(isStartedFromContentsScreen == 1)
                    whichExperiment = evalin('base', 'whichExperiment');
                    whichStartingYear = evalin('base', 'whichStartingYear');
                    whichFinalYear = evalin('base', 'whichFinalYear');
                    whichVariable = evalin('base', 'whichVariable');
                    whichModel = evalin('base', 'whichModel');
                    
                    activeSpecificExperiment(app,whichExperiment);

                    app.StartingYearSpinner.Value = whichStartingYear;
                    app.FinalYearSpinner.Value = whichFinalYear;
                    if (strcmp(whichExperiment,'SSP5-3.4OS'))
                        app.StartingYearSpinner.Limits = [2040 2100];
                        app.FinalYearSpinner.Limits = [2041 2100];
                    else
                        app.StartingYearSpinner.Limits = [2015 2100];
                        app.FinalYearSpinner.Limits = [2016 2100];
                    end

                    activeSpecificVariable(app,whichVariable);

                    % Aggiungo il path del Backend ai path di Matlab per poter
                    % chiamare gli script contenuti in esso
                    addpath(app.pathToBackend);
                    
                    enableAvailableModel(app, ...
                        app.experimentDictionary(app.ExperimentButtonGroup.SelectedObject.Text), ...
                        app.variableDictionary(app.VariableButtonGroup.SelectedObject.Text), ...
                        app.StartingYearSpinner.Value, ...
                        app.FinalYearSpinner.Value);
                    activeSpecificModel(app,whichModel);
                    
                else
                    assignin('base', 'whichExperiment', 'SSP1-1.9');
                    assignin('base', 'whichStartingYear', 2015);
                    assignin('base', 'whichFinalYear', 2024);
                    assignin('base', 'whichVariable', 'Snow depth');
                    assignin('base', 'whichModel', 'EC-Earth3-AerChem (Europe)');
                    
                    addpath(app.pathToBackend);
                    enableAvailableModel(app, ...
                        app.experimentDictionary(app.SSP119Button.Text), ...
                        app.variableDictionary(app.SnowdepthButton.Text), ...
                        app.StartingYearSpinner.Value, ...
                        app.FinalYearSpinner.Value);
                end
            catch
                assignin('base', 'whichExperiment', 'SSP1-1.9');
                assignin('base', 'whichStartingYear', 2015);
                assignin('base', 'whichFinalYear', 2024);
                assignin('base', 'whichVariable', 'Snow depth');
                assignin('base', 'whichModel', 'EC-Earth3-AerChem (Europe)');
                
                addpath(app.pathToBackend);
                enableAvailableModel(app, ...
                    app.experimentDictionary(app.SSP119Button.Text), ...
                    app.variableDictionary(app.SnowdepthButton.Text), ...
                    app.StartingYearSpinner.Value, ...
                    app.FinalYearSpinner.Value);
            end

        end

        % Selection changed function: ExperimentButtonGroup
        function ExperimentButtonGroupSelectionChanged(app, event)
            selectedButton = app.ExperimentButtonGroup.SelectedObject;
            selectedExperiment = selectedButton.Text;
            assignin('base', 'whichExperiment', selectedExperiment);
            if (strcmp(selectedExperiment,'SSP5-3.4OS'))
                app.StartingYearSpinner.Limits = [2040 2100];
                app.FinalYearSpinner.Limits = [2041 2100];
                valueStart = app.StartingYearSpinner.Value;
                valueFinal = app.FinalYearSpinner.Value;
                assignin('base', 'whichStartingYear', valueStart);
                assignin('base', 'whichFinalYear', valueFinal);
            else
                app.StartingYearSpinner.Limits = [2015 2100];
                app.FinalYearSpinner.Limits = [2016 2100];
                valueStart = app.StartingYearSpinner.Value;
                valueFinal = app.FinalYearSpinner.Value;
                assignin('base', 'whichStartingYear', valueStart);
                assignin('base', 'whichFinalYear', valueFinal);
            end
            enableAvailableModel(app, ...
                app.experimentDictionary(app.ExperimentButtonGroup.SelectedObject.Text), ...
                app.variableDictionary(app.VariableButtonGroup.SelectedObject.Text), ...
                app.StartingYearSpinner.Value, ...
                app.FinalYearSpinner.Value);
        end

        % Selection changed function: VariableButtonGroup
        function VariableButtonGroupSelectionChanged(app, event)
            selectedButton = app.VariableButtonGroup.SelectedObject;
            selectedVariable = selectedButton.Text;
            assignin('base', 'whichVariable', selectedVariable);
            enableAvailableModel(app, ...
                app.experimentDictionary(app.ExperimentButtonGroup.SelectedObject.Text), ...
                app.variableDictionary(app.VariableButtonGroup.SelectedObject.Text), ...
                app.StartingYearSpinner.Value, ...
                app.FinalYearSpinner.Value);
        end

        % Selection changed function: ModelButtonGroup
        function ModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelButtonGroup.SelectedObject;
            selectedModel = selectedButton.Text;
            assignin('base', 'whichModel', selectedModel);
        end

        % Value changed function: StartingYearSpinner
        function StartingYearSpinnerValueChanged(app, event)
            value = app.StartingYearSpinner.Value;
            assignin('base', 'whichStartingYear', value);
            app.FinalYearSpinner.Limits = [(value+1) 2100];
            valueFinal = app.FinalYearSpinner.Value;
            assignin('base', 'whichFinalYear', valueFinal);
        end

        % Value changed function: FinalYearSpinner
        function FinalYearSpinnerValueChanged(app, event)
            value = app.FinalYearSpinner.Value;
            assignin('base', 'whichFinalYear', value);
            if (strcmp(app.ExperimentButtonGroup.SelectedObject.Text,'SSP5-3.4OS'))
                app.StartingYearSpinner.Limits = [2040 (value-1)];
                valueStart = app.StartingYearSpinner.Value;
                assignin('base', 'whichStartingYear', valueStart);
            else
                app.StartingYearSpinner.Limits = [2015 (value-1)];
                valueStart = app.StartingYearSpinner.Value;
                assignin('base', 'whichStartingYear', valueStart);
            end
        end

        % Button pushed function: DownloadProcessandViewDataButton
        function DownloadProcessandViewDataButtonPushed(app, event)
            try
                whichModel = evalin('base', 'whichModel');
            catch
                warndlg('It is mandatory to select a model', 'Warning');
                return;
            end
            whichExperiment = evalin('base', 'whichExperiment');
            whichVariable = evalin('base', 'whichVariable');
            whichStartingYear = evalin('base', 'whichStartingYear');
            whichFinalYear = evalin('base', 'whichFinalYear');

            [experiment, variable, yearsString, availableModels] = ...
                selezionaParametri(app.experimentDictionary(whichExperiment),...
                app.variableDictionary(whichVariable),whichStartingYear,whichFinalYear);
            assignin('base', 'yearsString', yearsString);
            assignin('base', 'variable', variable);

            % Trovo la posizione della stringa nell'array
            position = find(strcmp(availableModels, whichModel));

            % Ottengo la stringa del modello selezionato scritta nella corretta convenzione
            [model] = selezionaModello(availableModels,position);

            d = uiprogressdlg(app.UIFigure,'Title','Download Data','Indeterminate','on');
            % Faccio la richiesta
            path1 = fullfile(app.pathToMLAPP, '..', 'backend', 'dataset');
            % N.B.: La richiesta assegna subito un valore alla 
            %       variabile globale response(i valori possibili sono completed|queued|running|failed). 
            %       Se la richiesta è rapida e funziona al primo tentativo, 
            %       allora essa assegna il valore completed e scarica subito
            %       i dati. In caso contrario assegna un altro valore e
            %       scarica i dati appena possibile.
            % N.B.: Come è scritto nella documentazione: "This is not a true 
            %       Future: the operation does not take place on a seperate thread. 
            % N.B.: Come è scritto nella documentazione: "Download of files 
            %       will not take place until you interact with the object, 
            %       either through checking Status or using wait()."
            [~] = makeRequest(experiment,variable,model,yearsString, true, path1);

            global response;
            % Controllo il risultato della richiesta
            status = '';
            while (~strcmp(response.State, "completed"))
                if(~strcmp(response.State, status))
                    fprintf('REQUEST STATUS: %s\n', response.State);
                    status = response.State;
                end
                if(strcmp(response.State, "failed"))
                    break;
                end
                pause(1);
            end
            if(strcmp(response.State, "failed"))
                close(d);
                errordlg('Unfortunately there was an error, please try again','Error');
                cd(app.pathToBackend);
                return;
            end
            if (strcmp(response.State, "completed"))
                downloadedFilePaths = response.OutputArguments{1};
                % Estrai il percorso relativo senza il nome del file
                [relative_path, name, ext] = fileparts(downloadedFilePaths(1));
                pathToDatasetFolder = fullfile('\\?\', app.pathToBackend, 'dataset', relative_path);
                pathToDataset = fullfile('\\?\', app.pathToBackend, 'dataset', downloadedFilePaths(1));
                datasetName = strcat(name, ext);
                assignin('base', 'pathToDatasetFolder', pathToDatasetFolder);
                assignin('base', 'pathToDataset', pathToDataset);
                assignin('base', 'datasetName', datasetName);
                close(d);
                cd(app.pathToBackend);
                
                path0 = fullfile(app.pathToMLAPP, '.');
                delete(app.UIFigure)
                addpath(genpath(path0));
                addpath(pathToDatasetFolder);
                ContentScreen
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            evalin('base', 'clear variables');
            evalin('base', 'clc');
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.ExperimentButtonGroup = uibuttongroup(app.Panel);
            app.ExperimentButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExperimentButtonGroupSelectionChanged, true);
            app.ExperimentButtonGroup.Title = 'Experiment';
            app.SSP370Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP370Button.Text = 'SSP3-7.0';
            app.SSP245Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP245Button.Text = 'SSP2-4.5';
            app.SSP434Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP434Button.Text = 'SSP4-3.4';
            app.SSP119Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP119Button.Text = 'SSP1-1.9';
            app.SSP119Button.Value = true;
            app.SSP126Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP126Button.Text = 'SSP1-2.6';
            app.SSP534OSButton = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP534OSButton.Text = 'SSP5-3.4OS';
            app.SSP460Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP460Button.Text = 'SSP4-6.0';
            app.SSP585Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP585Button.Text = 'SSP5-8.5';
            app.YearPanel = uipanel(app.Panel);
            app.YearPanel.Title = 'Year';
            app.StartingYearSpinner = uispinner(app.YearPanel);
            app.StartingYearSpinner.Limits = [2015 2100];
            app.StartingYearSpinner.ValueDisplayFormat = '%.0f';
            app.StartingYearSpinner.ValueChangedFcn = createCallbackFcn(app, @StartingYearSpinnerValueChanged, true);
            app.StartingYearSpinner.Value = 2015;
            app.FinalYearSpinner = uispinner(app.YearPanel);
            app.FinalYearSpinner.Limits = [2016 2100];
            app.FinalYearSpinner.ValueDisplayFormat = '%.0f';
            app.FinalYearSpinner.ValueChangedFcn = createCallbackFcn(app, @FinalYearSpinnerValueChanged, true);
            app.FinalYearSpinner.Value = 2024;
            app.FinalYearLabel = uilabel(app.YearPanel);
            app.FinalYearLabel.Text = 'Final Year';
            app.StartingYearLabel = uilabel(app.YearPanel);
            app.StartingYearLabel.Text = 'Starting Year';
            app.Panel_2 = uipanel(app.Panel);
            app.Label0 = uilabel(app.Panel_2);
            app.Label0.Text = 'CMIP6 Climate Projections Visualizer';
            app.Panel_3 = uipanel(app.Panel);
            app.Image2 = uiimage(app.Panel_3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'res', 'top_banner_3.png');
            app.VariableButtonGroup = uibuttongroup(app.Panel);
            app.VariableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VariableButtonGroupSelectionChanged, true);
            app.VariableButtonGroup.Title = 'Variable';
            app.SurfacesnowamountButton = uiradiobutton(app.VariableButtonGroup);
            app.SurfacesnowamountButton.Text = 'Surface snow amount';
            app.SnowfallfluxButton = uiradiobutton(app.VariableButtonGroup);
            app.SnowfallfluxButton.Text = 'Snowfall flux';
            app.SnowdepthButton = uiradiobutton(app.VariableButtonGroup);
            app.SnowdepthButton.Text = 'Snow depth';
            app.SnowdepthButton.Value = true;
            app.ModelButtonGroup = uibuttongroup(app.Panel);
            app.ModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelButtonGroupSelectionChanged, true);
            app.ModelButtonGroup.Title = 'Model';
            app.CNRMCM61FranceButton = uiradiobutton(app.ModelButtonGroup);
            app.CNRMCM61FranceButton.Enable = 'off';
            app.CNRMCM61FranceButton.Text = 'CNRM-CM6-1 (France)';
            app.CNRMESM21FranceButton = uiradiobutton(app.ModelButtonGroup);
            app.CNRMESM21FranceButton.Enable = 'off';
            app.CNRMESM21FranceButton.Text = 'CNRM-ESM2-1 (France)';
            app.ECEarth3AerChemEuropeButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3AerChemEuropeButton.Enable = 'off';
            app.ECEarth3AerChemEuropeButton.Text = 'EC-Earth3-AerChem (Europe)';
            app.ECEarth3VegEuropeButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3VegEuropeButton.Text = 'EC-Earth3-Veg (Europe)';
            app.ECEarth3VegEuropeButton.Value = true;
            app.ECEarth3EuropeButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3EuropeButton.Text = 'EC-Earth3 (Europe)';
            app.CNRMCM61HRFranceButton = uiradiobutton(app.ModelButtonGroup);
            app.CNRMCM61HRFranceButton.Enable = 'off';
            app.CNRMCM61HRFranceButton.Text = 'CNRM-CM6-1-HR (France)';
            app.CMCCCM2SR5ItalyButton = uiradiobutton(app.ModelButtonGroup);
            app.CMCCCM2SR5ItalyButton.Enable = 'off';
            app.CMCCCM2SR5ItalyButton.Text = 'CMCC-CM2-SR5 (Italy)';
            app.HadGEM3GC31LLUKButton = uiradiobutton(app.ModelButtonGroup);
            app.HadGEM3GC31LLUKButton.Enable = 'off';
            app.HadGEM3GC31LLUKButton.Text = 'HadGEM3-GC31-LL (UK)';
            app.ECEarth3VegLREuropeButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3VegLREuropeButton.Text = 'EC-Earth3-Veg-LR (Europe)';
            app.AWICM11MRGermanyButton = uiradiobutton(app.ModelButtonGroup);
            app.AWICM11MRGermanyButton.Enable = 'off';
            app.AWICM11MRGermanyButton.Text = 'AWI-CM-1-1-MR (Germany)';
            app.NorESM2MMNorwayButton = uiradiobutton(app.ModelButtonGroup);
            app.NorESM2MMNorwayButton.Enable = 'off';
            app.NorESM2MMNorwayButton.Text = 'NorESM2-MM (Norway)';
            app.HadGEM3GC31MMUKButton = uiradiobutton(app.ModelButtonGroup);
            app.HadGEM3GC31MMUKButton.Enable = 'off';
            app.HadGEM3GC31MMUKButton.Text = 'HadGEM3-GC31-MM (UK)';
            app.ECEarth3CCEuropeButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3CCEuropeButton.Enable = 'off';
            app.ECEarth3CCEuropeButton.Text = 'EC-Earth3-CC (Europe)';
            app.DownloadProcessandViewDataButton = uibutton(app.Panel, 'push');
            app.DownloadProcessandViewDataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadProcessandViewDataButtonPushed, true);
            app.DownloadProcessandViewDataButton.Icon = fullfile(pathToMLAPP, 'res', 'diagram_icon.png');
            app.DownloadProcessandViewDataButton.Text = 'Download, Process and View Data';
            app.GeographicalAreaButtonGroup = uibuttongroup(app.Panel);
            app.GeographicalAreaButtonGroup.Title = 'Geographical Area';
            app.WholeavailableregionButton = uiradiobutton(app.GeographicalAreaButtonGroup);
            app.WholeavailableregionButton.Enable = 'off';
            app.WholeavailableregionButton.Text = 'Whole available region';
            app.SubregionextractionButton = uiradiobutton(app.GeographicalAreaButtonGroup);
            app.SubregionextractionButton.Enable = 'off';
            app.SubregionextractionButton.Text = 'Sub-region extraction';
            app.SubregionextractionButton.Value = true;
            app.InfoPanel = uipanel(app.Panel);
            app.InfoPanel.Title = 'Info';
            app.Label = uilabel(app.InfoPanel);
            app.Label.Text = {'Please note that the processing time will depend on the amount of data you want to process,'; 'and the speed of your internet connection!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WelcomeScreen

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end